classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DonthaveanaccountLabel  matlab.ui.control.Label
        PasswordEditField       matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        UsernameEditField       matlab.ui.control.EditField
        UsernameEditFieldLabel  matlab.ui.control.Label
        CreateButton            matlab.ui.control.Button
        LoginButton             matlab.ui.control.Button
        Image                   matlab.ui.control.Image
    end


    properties (Access = private)
        MainApp % Main app that has the logic
        password
        username 
        WrongCounter
        UsersCount
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.WrongCounter = 0;
            app.UsersCount = 0;
            app.username = {};
            app.password = {};
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.LoginButton.Text = "Submit";
            app.DonthaveanaccountLabel.Visible = "off";
            app.CreateButton.Visible = "off";
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete both apps

            delete(app)

        end

        % Callback function
        function SubmitButtonPushed(app, event)
            app.password = app.EnteryourpasswordEditField.Value;
            msgbox("Password created successfully!");
            app.EnteryourpasswordEditField.Enable = "off";
            app.EnterYourPasswordLabel.Enable = "off";
            app.SubmitButton.Enable = "off";
            app.LoginButton.Enable = "on";
            %delete(app)
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
        Correctflag = 0;
        
        file = jsondecode(fileread("database.json"));
        app.username = fieldnames(file);
        app.UsersCount = length(app.username);
        
        for i = 1:app.UsersCount
            app.password{i} = getfield(file,app.username{i});
        end
        
        
        if strcmp(app.LoginButton.Text, "Login")
           for users = 1:app.UsersCount
               if strcmp(app.UsernameEditField.Value, app.username{users}) && strcmp(app.PasswordEditField.Value, app.password{users})
                    app.LoginButton.Enable = "off";
                    app.MainApp = mainapp(app);
                    Correctflag = 1;
                    break;
               end
           
           end
           
           if Correctflag == 0
                app.WrongCounter = app.WrongCounter + 1;
                msgbox("Wrong Password!","Error","error");
           end
            
            
            if app.WrongCounter == 3
                delete(app)
            end
           
        else
            app.UsersCount = app.UsersCount + 1;
            
            app.username{end+1} = app.UsernameEditField.Value;
            app.password{end+1} = app.PasswordEditField.Value;
            
            for i = 1:app.UsersCount
                file2.(app.username{i}) = app.password{i};
            end
            
            file_json = jsonencode(file2,'PrettyPrint',true);
                
            fileID = fopen('database.json','w');
            fprintf(fileID,'%s',file_json);
            fclose(fileID);
            
            %msgbox(sprintf("%s\n%s",app.UsernameEditField.Value,app.PasswordEditField.Value));
            app.LoginButton.Text = "Login";
            app.DonthaveanaccountLabel.Visible = "on";
            app.CreateButton.Visible = "on";
        end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '0001.jpg';
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Tooltip = {'Press to access your account!'};
            app.LoginButton.Text = 'Login';
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Tooltip = {'Create an account if you don''t have one!'};
            app.CreateButton.Text = {'Create'; ''};
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.UsernameEditField.Tooltip = {'Enter your username'};
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditField.Tooltip = {'Enter your password'};
            app.DonthaveanaccountLabel = uilabel(app.UIFigure);
            app.DonthaveanaccountLabel.Text = 'Don''t have an account?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end