classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ProjectMenu                matlab.ui.container.Menu
        ResetMenu                  matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        TipsandTrixMenu            matlab.ui.container.Menu
        Toolbar                    matlab.ui.container.Toolbar
        PushTool                   matlab.ui.container.toolbar.PushTool
        PushTool2                  matlab.ui.container.toolbar.PushTool
        TabGroup                   matlab.ui.container.TabGroup
        Output13Tab                matlab.ui.container.Tab
        MeasurementsPanel          matlab.ui.container.Panel
        UITable                    matlab.ui.control.Table
        GraphsPanel                matlab.ui.container.Panel
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        ModelParametersPanel       matlab.ui.container.Panel
        dmEditField                matlab.ui.control.NumericEditField
        dmEditFieldLabel           matlab.ui.control.Label
        DrawButton                 matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        PowerUnitsDropDown         matlab.ui.control.DropDown
        PowerUnitsDropDownLabel    matlab.ui.control.Label
        NumberofSamplesKnob        matlab.ui.control.Knob
        NumberofSamplesLabel       matlab.ui.control.Label
        PldoEditField              matlab.ui.control.NumericEditField
        PldoEditFieldLabel         matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel             matlab.ui.control.Label
        nEditField                 matlab.ui.control.NumericEditField
        nEditFieldLabel            matlab.ui.control.Label
        domEditField               matlab.ui.control.NumericEditField
        domEditFieldLabel          matlab.ui.control.Label
        Output4Tab                 matlab.ui.container.Tab
        CalculateButtonGroup       matlab.ui.container.ButtonGroup
        SeperationButton           matlab.ui.control.RadioButton
        TimeButton                 matlab.ui.control.RadioButton
        UnitsPanel                 matlab.ui.container.Panel
        PowerUnitsDropDown_2       matlab.ui.control.DropDown
        PowerUnitsDropDown_2Label  matlab.ui.control.Label
        ReportButton               matlab.ui.control.Button
        ModelParametersPanel_2     matlab.ui.container.Panel
        EditField_2                matlab.ui.control.NumericEditField
        EditField_2Label           matlab.ui.control.Label
        nEditField_2               matlab.ui.control.NumericEditField
        nEditField_2Label          matlab.ui.control.Label
        dEditField                 matlab.ui.control.NumericEditField
        dEditFieldLabel            matlab.ui.control.Label
        domEditField_2             matlab.ui.control.NumericEditField
        domEditField_2Label        matlab.ui.control.Label
        ExtraParametersPanel       matlab.ui.container.Panel
        TimeEditField              matlab.ui.control.NumericEditField
        TimeLabel                  matlab.ui.control.Label
        RequiredPREditField        matlab.ui.control.NumericEditField
        RequiredPREditFieldLabel   matlab.ui.control.Label
        AntennaParametersPanel     matlab.ui.container.Panel
        FMHzEditField              matlab.ui.control.NumericEditField
        FMHzEditFieldLabel         matlab.ui.control.Label
        GrdBEditField              matlab.ui.control.NumericEditField
        GrdBEditFieldLabel         matlab.ui.control.Label
        GtdBEditField              matlab.ui.control.NumericEditField
        GtdBEditFieldLabel         matlab.ui.control.Label
        PtdBEditField              matlab.ui.control.NumericEditField
        PtdBEditFieldLabel         matlab.ui.control.Label
        CommandWindowLabel         matlab.ui.control.Label
        CommandWindowTextArea      matlab.ui.control.TextArea
    end


    properties (Access = private)
        measurements_array % Description
        d_array
        number_of_samples
        CallingApp % login App
        number_of_samples_per_distance_array_decimal
        number_of_samples_per_distance_array_dB
        number_of_samples_per_distance_array_dBm
        n_estimated_array_decimal
        sigma_estimated_array_decimal
        n_estimated_array_dB
        sigma_estimated_array_dB
        n_estimated_array_dBm
        sigma_estimated_array_dBm
        t2
        fields_array % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, password)
            app.CallingApp  = caller;
            %msgbox("Welcome Back");
            %app.d_array = {" "};
            app.CommandWindowTextArea.Value = sprintf('[Obelos]: Welcome To Obelos Propagation Simulator!\n[Obelos]: You can do different operations using Obelos Simulator.\n ');
            app.number_of_samples_per_distance_array_decimal = [];
            app.number_of_samples_per_distance_array_dB      = [];
            app.number_of_samples_per_distance_array_dBm     = [];
            app.n_estimated_array_decimal                    = [];
            app.sigma_estimated_array_decimal                = [];
            app.n_estimated_array_dB                         = [];
            app.sigma_estimated_array_dB                     = [];
            app.n_estimated_array_dBm                        = [];
            app.sigma_estimated_array_dBm                    = [];
            app.number_of_samples                            = 50;
            %delete(app.CallingApp);
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            %Read Json File
            file_json = uigetfile({'*.json'},'Select Input Parameters');
            file = jsondecode(fileread(file_json));
            fields = fieldnames(file.ModelParameters.Measurements);
            app.domEditField.Value = file.ModelParameters.do;
            app.PldoEditField.Value = file.ModelParameters.Pl_do;


            temp = fields;

            corrupted_file = 0;
            app.domEditField.Value = file.ModelParameters.do;

            %Parse Values
            for counter = 1 : 1 : length(fields)

                fields{counter}(1) = " ";
                x = str2num(fields{counter}(1,:));

                if x  < app.domEditField.Value

                    %Reset All Line Edits Values and Color Font
                    cla(app.UIAxes)
                    app.PldoEditField.Value  = 0;
                    app.UITable.Data = [];
                    app.nEditField.Value     = 0;
                    app.EditField.Value      = 0;
                    app.SamplesEditField.Value  = 0;
                    app.CommandWindowTextArea.FontColor = [0.00,0.00,0.00];
                    app.CommandWindowTextArea.Value = sprintf('[Obelos]: d = %d is smaller than do = %d \n[Obelos]: File is corrupted. Process is terminated ' ,x , app.domEditField.Value);
                    corrupted_file = 1 ;
                    return;
                end

                app.fields_array(counter) = x;
                app.measurements_array = [ app.measurements_array  getfield(file.ModelParameters.Measurements,temp{counter}) ];

            end
            powerunits = app.PowerUnitsDropDown.Value;

            %Output on command window
            if corrupted_file == 0
                app.t2= table(app.fields_array.',app.measurements_array.');
                app.UITable.Data = app.t2;

                if strcmp(powerunits,"dB")
                    PL_do_decimal = app.PldoEditField.Value;
                    PL_do_dB = 10*log10(PL_do_decimal);
                    PL_d_dBm = 10*log10(app.measurements_array);
                    [n_estimated_value,sigma_estimated_value,PL_d,model] = estimation_file(PL_d_dBm,app.fields_array,PL_do_dB,app.domEditField.Value);
                    PL_d_dB  = 10*log10(PL_d);
                    model_dB = 10*log10(model);
                    scatter(app.UIAxes3,app.fields_array,PL_d_dB);
                    xlim(app.UIAxes3,[0, max(app.fields_array)+10]);
                    ylim(app.UIAxes3,[0, 15]);
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.fields_array,model_dB);
                    hold(app.UIAxes3,'off')
                    app.UIAxes3.Title.String = sprintf("Scatter Plot for n = %f" , n_estimated_value);
                    app.UIAxes3.YLabel.String ="Path Loss (dB)";
                    app.CommandWindowTextArea.Value =sprintf('[Obelos]: n estimated %f \n[Obelos]: sigma estimated %f',n_estimated_value,sigma_estimated_value);
                elseif  strcmp(powerunits,"decimal")
                    app.UIAxes3.YLabel.String ="Path Loss (decimal)";
                    [n_estimated_value,sigma_estimated_value,avg_array,model] = estimation_file(app.measurements_array,app.fields_array,app.PldoEditField.Value,app.domEditField.Value);
                    app.CommandWindowTextArea.Value =sprintf('[Obelos]: n estimated %f \n[Obelos]: sigma estimated %f',n_estimated_value,sigma_estimated_value);
                    scatter(app.UIAxes3,app.fields_array,avg_array);
                    xlim(app.UIAxes3,[0, max(app.fields_array)+10]);
                    ylim(app.UIAxes3,[0, 100]);
                    app.UIAxes3.Title.String = sprintf("Scatter Plot for n = %f" , n_estimated_value);
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.fields_array,model);
                    hold(app.UIAxes3,'off')
                elseif strcmp(powerunits,"dBm")
                    PL_do_decimal = app.PldoEditField.Value;
                    PL_do_dB = 10*log10(PL_do_decimal);
                    PL_do_dBm = PL_do_dB + 30;
                    PL_d_dBm = 10*log10(app.measurements_array) + 30;
                    [n_estimated_value,sigma_estimated_value,PL_d,model] = estimation_file(PL_d_dBm,app.fields_array,PL_do_dBm,app.domEditField.Value);
                    PL_d_dB  = 10*log10(PL_d);
                    model_dBm = 10*log10(model);
                    scatter(app.UIAxes3,app.fields_array,PL_d_dB);
                    xlim(app.UIAxes3,[0, max(app.fields_array)+10]);
                    ylim(app.UIAxes3,[0, 20]);
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.fields_array,model_dBm);
                    hold(app.UIAxes3,'off')
                    app.UIAxes3.Title.String = sprintf("Scatter Plot for n = %f" , n_estimated_value);
                    app.UIAxes3.YLabel.String ="Path Loss (dBm)";
                    app.CommandWindowTextArea.Value =sprintf('[Obelos]: n estimated %f \n[Obelos]: sigma estimated %f',n_estimated_value,sigma_estimated_value);
                end


                app.CommandWindowTextArea.Value = sprintf('[Obelos]: File is Imported Successfully!\n[Obelos]: Figure is Plotted\n[Obelos]: n estimated = %f \n[Obelos]: sigma estimated = %f',n_estimated_value,sigma_estimated_value);
                app.CommandWindowTextArea.FontColor = [0.47,0.67,0.19];  %Green
            end
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)

            file2.ModelParameters.d  = app.dmEditField.Value;
            file2.ModelParameters.do = app.domEditField.Value;
            file2.ModelParameters.n  = app.nEditField.Value;
            file2.ModelParameters.x_ = app.EditField.Value;
            file2.ModelParameters.k  = app.SamplesEditField.Value;
            file2.ModelParameters.Measurnments = {};
            file_json = jsonencode(file2,'PrettyPrint',true);
            fileID = fopen('Output.json','w');
            fprintf(fileID,'%s',file_json);
            fclose(fileID);
            %Output on command window
            app.CommandWindowTextArea.Value = sprintf('[Obelos]: File is Exported Successfully!');
            app.CommandWindowTextArea.FontColor = [0.47,0.67,0.19];
        end

        % Value changed function: PowerUnitsDropDown
        function PowerUnitsDropDownValueChanged(app, event)
            value = app.PowerUnitsDropDown.Value;
            app.CommandWindowTextArea.Value = sprintf('[Obelos]: Power Units are %s',value);
        end

        % Button pushed function: DrawButton
        function SubmitButtonPushed(app, event)
            powerunits = app.PowerUnitsDropDown.Value;
            if strcmp(powerunits,"decimal")
                PL_do_decimal = app.PldoEditField.Value;
                [n_estimated_value,sigma_estimated_value,PL_d_decimal,model] = estimation_f(app.EditField.Value,app.d_array,PL_do_decimal,app.nEditField.Value,app.domEditField.Value,app.number_of_samples);
                scatter(app.UIAxes3,app.d_array,PL_d_decimal);
                xlim(app.UIAxes3,[0, max(app.d_array)+10]);
                ylim(app.UIAxes3,[0, 100]);
                hold(app.UIAxes3,'on')
                plot(app.UIAxes3,app.d_array,model);
                hold(app.UIAxes3,'off')
                app.UIAxes3.Title.String = sprintf("Scatter Plot for n = %d" , app.nEditField.Value);
                app.UIAxes3.YLabel.String ="Path Loss (decimal)";
                app.CommandWindowTextArea.Value =sprintf('[Obelos]: n estimated %f \n[Obelos]: sigma estimated %f',n_estimated_value,sigma_estimated_value);

            elseif strcmp(powerunits,"dB")
                PL_do_decimal = app.PldoEditField.Value;
                PL_do_dB = 10*log10(PL_do_decimal);
                [n_estimated_value,sigma_estimated_value,PL_d,model] = estimation_f(app.EditField.Value,app.d_array,PL_do_dB,app.nEditField.Value,app.domEditField.Value,app.number_of_samples);
                PL_d_dB=10*log10(PL_d);
                model_dB=10*log10(model);
                scatter(app.UIAxes3,app.d_array,PL_d_dB);
                xlim(app.UIAxes3,[0, max(app.d_array)+10]);
                ylim(app.UIAxes3,[0, 20]);
                hold(app.UIAxes3,'on')
                plot(app.UIAxes3,app.d_array,model_dB);
                hold(app.UIAxes3,'off')
                app.UIAxes3.Title.String = sprintf("Scatter Plot for n = %d" , app.nEditField.Value);
                app.UIAxes3.YLabel.String ="Path Loss (dB)";
                app.CommandWindowTextArea.Value =sprintf('[Obelos]: n estimated %f \n[Obelos]: sigma estimated %f',n_estimated_value,sigma_estimated_value);

            elseif strcmp(powerunits,"dBm")
                PL_do_decimal = app.PldoEditField.Value;
                PL_do_dB = 10*log10(PL_do_decimal);
                PL_do_dBm = PL_do_dB + 30;
                [n_estimated_value,sigma_estimated_value,PL_d,model] = estimation_f(app.EditField.Value,app.d_array,PL_do_dBm,app.nEditField.Value,app.domEditField.Value,app.number_of_samples);
                PL_d_dBm = 10*log10(PL_d);
                model_dBm = 10*log10(model);
                scatter(app.UIAxes3,app.d_array,PL_d_dBm);
                xlim(app.UIAxes3,[0, max(app.d_array)+10]);
                ylim(app.UIAxes3,[0, 30]);
                hold(app.UIAxes3,'on')
                plot(app.UIAxes3,app.d_array,model_dBm);
                hold(app.UIAxes3,'off')
                app.UIAxes3.Title.String = sprintf("Scatter Plot for n = %d" , app.nEditField.Value);
                app.UIAxes3.YLabel.String ="Path Loss (dBm)";
                app.CommandWindowTextArea.Value =sprintf('[Obelos]: n estimated %f \n[Obelos]: sigma estimated %f',n_estimated_value,sigma_estimated_value);
            end
        end

        % Callback function
        function EnterButtonPushed(app, event)
            if app.dmEditField.Value > app.domEditField.Value

                if isempty(find(app.d_array == app.dmEditField.Value, 1 ))

                    app.UITable.Data = [];

                    app.d_array = [app.d_array  app.dmEditField.Value] ;

                    t= table(app.d_array.');
                    app.UITable.Data = t;
                    app.CommandWindowTextArea.FontColor = [0.47,0.67,0.19];
                    app.CommandWindowTextArea.Value = sprintf("[Obelos]: Distance d = %d is added to Measurements.",app.dmEditField.Value);

                else

                    app.CommandWindowTextArea.FontColor = [0.00,0.00,0.00];
                    app.CommandWindowTextArea.Value = sprintf("[Obelos]: Distance d = %d is already in Measurements.",app.dmEditField.Value);

                end

            else
                app.CommandWindowTextArea.FontColor = [0.00,0.00,0.00];
                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Distance d = %d is less than do = %d ",app.dmEditField.Value,app.domEditField.Value);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % reEnable the button in login app
            %app.CallingApp.CreateButton.Enable = "on";
            %delete(app.CallingApp)
            app.CallingApp.LoginButton.Enable = "on";
            delete(app)


        end

        % Value changing function: NumberofSamplesKnob
        function NumberofSamplesKnobValueChanging(app, event)
            changingValue = event.Value;
            powerunits = app.PowerUnitsDropDown.Value;
            app.number_of_samples = floor(event.Value);
            app.Label.Text = num2str((floor(changingValue)));
            PL_do_decimal = app.PldoEditField.Value;
            if strcmp(powerunits,"decimal")
                app.number_of_samples_per_distance_array_decimal = [app.number_of_samples_per_distance_array_decimal app.number_of_samples];
                [n_estimated_value,sigma_estimated_value,PL_d_decimal,model] = estimation_f(app.EditField.Value,app.d_array,PL_do_decimal,app.nEditField.Value,app.domEditField.Value,app.number_of_samples);
                app.n_estimated_array_decimal = [app.n_estimated_array_decimal n_estimated_value];
                app.sigma_estimated_array_decimal = [app.sigma_estimated_array_decimal sigma_estimated_value];
                scatter(app.UIAxes3,app.d_array,PL_d_decimal);
                app.UIAxes3.YLabel.String ="Path Loss (decimal)";
                xlim(app.UIAxes3,[0, max(app.d_array)+10]);
                ylim(app.UIAxes3,[0, 100]);

                hold(app.UIAxes3,'on')
                plot(app.UIAxes3,app.d_array,model);
                hold(app.UIAxes3,'off')


                plot(app.UIAxes,app.number_of_samples_per_distance_array_decimal,1-(abs(app.n_estimated_array_decimal - app.nEditField.Value))/app.nEditField.Value);
                xlim(app.UIAxes,[0, 600]);
                ylim(app.UIAxes,[0.95, 1]);
                plot(app.UIAxes2,app.number_of_samples_per_distance_array_decimal,1-(abs(app.sigma_estimated_array_decimal - app.EditField.Value))/app.EditField.Value);

                xlim(app.UIAxes2,[0, 600]);
                ylim(app.UIAxes2,[0.7, 1]);
                app.CommandWindowTextArea.Value =sprintf('[Obelos]: n estimated %f\n[Obelos]: sigma estimated %f',n_estimated_value,sigma_estimated_value);

            elseif strcmp(powerunits,"dB")
                app.number_of_samples_per_distance_array_dB = [app.number_of_samples_per_distance_array_dB app.number_of_samples];
                PL_do_decimal = app.PldoEditField.Value;
                PL_do_dB = 10*log10(PL_do_decimal);
                [n_estimated_value,sigma_estimated_value,PL_d,model] = estimation_f(app.EditField.Value,app.d_array,PL_do_dB,app.nEditField.Value,app.domEditField.Value,app.number_of_samples);
                PL_d_dB=10*log10(PL_d);
                model_dB=10*log10(model);
                app.n_estimated_array_dB = [app.n_estimated_array_dB n_estimated_value];
                app.sigma_estimated_array_dB = [app.sigma_estimated_array_dB sigma_estimated_value];
                scatter(app.UIAxes3,app.d_array,PL_d_dB);
                xlim(app.UIAxes3,[0, max(app.d_array)+10]);
                ylim(app.UIAxes3,[0, 20]);
                hold(app.UIAxes3,'on')
                plot(app.UIAxes3,app.d_array,model_dB);
                hold(app.UIAxes3,'off')
                app.UIAxes3.Title.String = sprintf("Scatter Plot for n = %d" , app.nEditField.Value);
                app.UIAxes3.YLabel.String ="Path Loss (dB)";
                plot(app.UIAxes,app.number_of_samples_per_distance_array_dB,1-(abs(app.n_estimated_array_dB - app.nEditField.Value))/app.nEditField.Value);
                xlim(app.UIAxes,[0, 600]);
                ylim(app.UIAxes,[0.95, 1]);
                plot(app.UIAxes2,app.number_of_samples_per_distance_array_dB,1-(abs(app.sigma_estimated_array_dB - app.EditField.Value))/app.EditField.Value);

                xlim(app.UIAxes2,[0, 600]);
                ylim(app.UIAxes2,[0.7, 1]);
                app.CommandWindowTextArea.Value =sprintf('[Obelos]: n estimated %f \n[Obelos]: sigma estimated %f',n_estimated_value,sigma_estimated_value);

            elseif strcmp(powerunits,"dBm")
                app.number_of_samples_per_distance_array_dBm = [app.number_of_samples_per_distance_array_dBm app.number_of_samples];
                PL_do_decimal = app.PldoEditField.Value;
                PL_do_dB = 10*log10(PL_do_decimal);
                PL_do_dBm = PL_do_dB + 30;
                [n_estimated_value,sigma_estimated_value,PL_d,model] = estimation_f(app.EditField.Value,app.d_array,PL_do_dBm,app.nEditField.Value,app.domEditField.Value,app.number_of_samples);
                PL_d_dBm = 10*log10(PL_d);
                model_dBm = 10*log10(model);
                app.n_estimated_array_dBm = [app.n_estimated_array_dBm n_estimated_value];
                app.sigma_estimated_array_dBm = [app.sigma_estimated_array_dBm sigma_estimated_value];
                scatter(app.UIAxes3,app.d_array,PL_d_dBm);
                xlim(app.UIAxes3,[0, max(app.d_array)+10]);
                ylim(app.UIAxes3,[0, 30]);
                hold(app.UIAxes3,'on')
                plot(app.UIAxes3,app.d_array,model_dBm);
                hold(app.UIAxes3,'off')
                app.UIAxes3.Title.String = sprintf("Scatter Plot for n = %d" , app.nEditField.Value);
                app.UIAxes3.YLabel.String ="Path Loss (dBm)";
                plot(app.UIAxes,app.number_of_samples_per_distance_array_dBm,1-(abs(app.n_estimated_array_dBm - app.nEditField.Value))/app.nEditField.Value);
                xlim(app.UIAxes,[0, 600]);
                ylim(app.UIAxes,[0.95, 1]);
                plot(app.UIAxes2,app.number_of_samples_per_distance_array_dBm,1-(abs(app.sigma_estimated_array_dBm - app.EditField.Value))/app.EditField.Value);
                xlim(app.UIAxes2,[0, 600]);
                ylim(app.UIAxes2,[0.7, 1]);
                app.CommandWindowTextArea.Value =sprintf('[Obelos]: n estimated %f \n[Obelos]: sigma estimated %f',n_estimated_value,sigma_estimated_value);
            end


        end

        % Menu selected function: TipsandTrixMenu
        function TipsandTrixMenuSelected(app, event)
            app.CommandWindowTextArea.FontColor = [0.00,0.00,0.00];
            app.CommandWindowTextArea.Value = sprintf("[Obelos]: Welcome From Tips and Trixs\n[Obelos]: You can Read the Data from a file or just enter in manually one by one..");
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            %Reset All Line Edits Values and Color Font
            cla(app.UIAxes3)
            cla(app.UIAxes2)
            cla(app.UIAxes)
            app.PldoEditField.Value                          = 0;
            app.d_array                                      = [];
            app.UITable.Data                                 = [];
            app.nEditField.Value                             = 0;
            app.EditField.Value                              = 0;
            app.domEditField.Value                           = 0;
            app.nEditField_2.Value                           = 0;
            app.EditField_2.Value                            = 0;
            app.domEditField_2.Value                         = 0;
            app.dEditField.Value                             = 0;
            app.PtdBEditField.Value                          = 0;
            app.GtdBEditField.Value                          = 0;
            app.GrdBEditField.Value                          = 0;
            app.FMHzEditField.Value                          = 0;
            app.RequiredPREditField.Value                    = 0;
            app.TimeEditField.Value                          = 0;
            app.CommandWindowTextArea.FontColor              = [0.00,0.00,0.00];
            app.CommandWindowTextArea.Value                  = sprintf("[Obelos]: Project is resetted.");
            app.number_of_samples_per_distance_array_decimal = [];
            app.number_of_samples_per_distance_array_dB      = [];
            app.number_of_samples_per_distance_array_dBm     = [];
            app.n_estimated_array_decimal                    = [];
            app.sigma_estimated_array_decimal                = [];
            app.n_estimated_array_dB                         = [];
            app.sigma_estimated_array_dB                     = [];
            app.n_estimated_array_dBm                        = [];
            app.sigma_estimated_array_dB                     = [];
            app.measurements_array                           = [];
            app.fields_array                                 = [];
            app.t2                                           = [];
        end

        % Button pushed function: ReportButton
        function ReportButtonPushed(app, event)
            powerunits = app.PowerUnitsDropDown_2.Value;

            Pr_do_dB = app.PtdBEditField.Value + app.GtdBEditField.Value + app.GrdBEditField.Value - 20 * log10(app.domEditField_2.Value) - 20 * log10( (4 * pi) /  (300000000 / (app.FMHzEditField.Value*10^6)) );

            if strcmp(app.CalculateButtonGroup.SelectedObject.Text,"Time")

                Pr_d_dB = Pr_do_dB - 10 * app.nEditField_2.Value * log10(app.dEditField.Value/app.domEditField_2.Value);


                if strcmp(powerunits,"dB")

                    app.TimeEditField.Value = 100 - (qfunc((Pr_d_dB-app.RequiredPREditField.Value)/app.EditField_2.Value) *100);


                elseif strcmp(powerunits,"dBm")

                    app.TimeEditField.Value = 100 - (qfunc((Pr_d_dB+30-app.RequiredPREditField.Value)/app.EditField_2.Value) *100);

                end

                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Time t = %f% ", app.TimeEditField.Value);

            elseif strcmp(app.CalculateButtonGroup.SelectedObject.Text,"Seperation")

                qfunction_input = qfuncinv((100-app.TimeEditField.Value)/100) ;

                Prd_Omega = qfunction_input * app.EditField_2.Value;

                if strcmp(powerunits,"dB")

                    Pr_d_dB = Prd_Omega + app.RequiredPREditField.Value;

                elseif strcmp(powerunits,"dBm")

                    Pr_d_dB = Prd_Omega + app.RequiredPREditField.Value - 30;

                end


                display(Pr_d_dB);

                log_ = ( -1 * (Pr_d_dB - Pr_do_dB) ) / (10 * app.nEditField_2.Value);

                dmax = ( 10 ^ log_ ) * app.domEditField_2.Value;


                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Max distance d = %f ",dmax);


            end
        end

        % Selection changed function: CalculateButtonGroup
        function CalculateButtonGroupSelectionChanged(app, event)
            selectedButton = app.CalculateButtonGroup.SelectedObject;

            if strcmp(selectedButton.Text,"Time")

                app.TimeEditField.Editable = 0;


                display("HELLO FROM Time");

            elseif strcmp(selectedButton.Text,"Seperation")

                display("HELLO FROM SEPERATION");


                app.TimeEditField.Editable = 1;

            end
        end

        % Callback function
        function NumberofSamplesKnob_2ValueChanging(app, event)
            changingValue = event.Value;

            app.Label_2.Text = num2str((floor(changingValue)));
        end

        % Value changed function: NumberofSamplesKnob
        function NumberofSamplesKnobValueChanged(app, event)
            value = app.NumberofSamplesKnob.Value;

        end

        % Value changed function: dmEditField
        function dmEditFieldValueChanged(app, event)
            if app.dmEditField.Value > app.domEditField.Value

                if isempty(find(app.d_array == app.dmEditField.Value, 1 ))

                    app.UITable.Data = [];

                    app.d_array = [app.d_array  app.dmEditField.Value] ;
                    app.d_array=sort(app.d_array);
                    t= table(app.d_array.');
                    app.UITable.Data = t;
                    app.CommandWindowTextArea.FontColor = [0.47,0.67,0.19];
                    app.CommandWindowTextArea.Value = sprintf("[Obelos]: Distance d = %d is added to Measurements.",app.dmEditField.Value);

                else

                    app.CommandWindowTextArea.FontColor = [0.00,0.00,0.00];
                    app.CommandWindowTextArea.Value = sprintf("[Obelos]: Distance d = %d is already in Measurements.",app.dmEditField.Value);

                end

            else
                app.CommandWindowTextArea.FontColor = [0.00,0.00,0.00];
                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Distance d = %d is less than do = %d ",app.dmEditField.Value,app.domEditField.Value);
            end

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if key == "return"
            end
        end

        % Button down function: Output4Tab
        function Output4TabButtonDown(app, event)
            app.TimeEditField.Editable = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.ResetMenu = uimenu(app.ProjectMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.TipsandTrixMenu = uimenu(app.HelpMenu);
            app.TipsandTrixMenu.MenuSelectedFcn = createCallbackFcn(app, @TipsandTrixMenuSelected, true);
            app.TipsandTrixMenu.Tooltip = {'Press to Show some help'};
            app.TipsandTrixMenu.Text = 'Tips and Trix';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Import Parameters'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = '243717.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Export Parameters'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = '7191951.png';
            app.CommandWindowTextArea = uitextarea(app.UIFigure);
            app.CommandWindowTextArea.Editable = 'off';
            app.CommandWindowTextArea.Placeholder = 'Welcome to Obelos!';
            app.CommandWindowLabel = uilabel(app.UIFigure);
            app.CommandWindowLabel.Text = 'Command Window';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Output13Tab = uitab(app.TabGroup);
            app.Output13Tab.Title = 'Output 1 : 3';
            app.ModelParametersPanel = uipanel(app.Output13Tab);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.domEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.domEditFieldLabel.Text = 'do (m)';
            app.domEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.nEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.EditFieldLabel = uilabel(app.ModelParametersPanel);
            app.EditFieldLabel.Text = 'σ';
            app.EditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.PldoEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.PldoEditFieldLabel.Text = 'Pl (do)';
            app.PldoEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.NumberofSamplesLabel = uilabel(app.ModelParametersPanel);
            app.NumberofSamplesLabel.Text = 'Number of Samples:';
            app.NumberofSamplesKnob = uiknob(app.ModelParametersPanel, 'continuous');
            app.NumberofSamplesKnob.Limits = [0 500];
            app.NumberofSamplesKnob.ValueChangedFcn = createCallbackFcn(app, @NumberofSamplesKnobValueChanged, true);
            app.NumberofSamplesKnob.ValueChangingFcn = createCallbackFcn(app, @NumberofSamplesKnobValueChanging, true);
            app.NumberofSamplesKnob.Value = 50;
            app.PowerUnitsDropDownLabel = uilabel(app.ModelParametersPanel);
            app.PowerUnitsDropDownLabel.Text = 'Power Units';
            app.PowerUnitsDropDown = uidropdown(app.ModelParametersPanel);
            app.PowerUnitsDropDown.Items = {'decimal', 'dB', 'dBm'};
            app.PowerUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PowerUnitsDropDownValueChanged, true);
            app.PowerUnitsDropDown.Value = 'decimal';
            app.Label = uilabel(app.ModelParametersPanel);
            app.Label.Text = '50';
            app.DrawButton = uibutton(app.ModelParametersPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.dmEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.dmEditFieldLabel.Text = 'd (m)';
            app.dmEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.dmEditField.ValueChangedFcn = createCallbackFcn(app, @dmEditFieldValueChanged, true);
            app.GraphsPanel = uipanel(app.Output13Tab);
            app.GraphsPanel.Title = 'Graphs';
            app.UIAxes = uiaxes(app.GraphsPanel);
            title(app.UIAxes, 'Path Exponent ( n ) ')
            xlabel(app.UIAxes, 'Number of Samples ')
            ylabel(app.UIAxes, 'Accuracy')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GraphsPanel);
            title(app.UIAxes2, 'Standard Diviation ( σ )')
            xlabel(app.UIAxes2, 'Number of Samples')
            ylabel(app.UIAxes2, 'Accuracy')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GraphsPanel);
            title(app.UIAxes3, 'Scatter Plot')
            xlabel(app.UIAxes3, 'distance d (m)')
            ylabel(app.UIAxes3, 'Path Loss')
            zlabel(app.UIAxes3, 'Z')
            app.MeasurementsPanel = uipanel(app.Output13Tab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.UITable = uitable(app.MeasurementsPanel);
            app.UITable.ColumnName = {'d (m)'; 'Pr(d)'};
            app.UITable.RowName = {};
            app.Output4Tab = uitab(app.TabGroup);
            app.Output4Tab.Title = 'Output 4';
            app.Output4Tab.ButtonDownFcn = createCallbackFcn(app, @Output4TabButtonDown, true);
            app.AntennaParametersPanel = uipanel(app.Output4Tab);
            app.AntennaParametersPanel.Title = 'Antenna Parameters';
            app.PtdBEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.PtdBEditFieldLabel.Text = 'Pt (dB)';
            app.PtdBEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.GtdBEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.GtdBEditFieldLabel.Text = 'Gt(dB)';
            app.GtdBEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.GrdBEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.GrdBEditFieldLabel.Text = 'Gr(dB)';
            app.GrdBEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.FMHzEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.FMHzEditFieldLabel.Text = 'F (MHz)';
            app.FMHzEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.ExtraParametersPanel = uipanel(app.Output4Tab);
            app.ExtraParametersPanel.Title = 'Extra Parameters';
            app.RequiredPREditFieldLabel = uilabel(app.ExtraParametersPanel);
            app.RequiredPREditFieldLabel.Text = 'Required PR';
            app.RequiredPREditField = uieditfield(app.ExtraParametersPanel, 'numeric');
            app.TimeLabel = uilabel(app.ExtraParametersPanel);
            app.TimeLabel.Text = 'Time ( %)';
            app.TimeEditField = uieditfield(app.ExtraParametersPanel, 'numeric');
            app.ModelParametersPanel_2 = uipanel(app.Output4Tab);
            app.ModelParametersPanel_2.Title = 'Model Parameters';
            app.domEditField_2Label = uilabel(app.ModelParametersPanel_2);
            app.domEditField_2Label.Text = 'do (m)';
            app.domEditField_2 = uieditfield(app.ModelParametersPanel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.ModelParametersPanel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.ModelParametersPanel_2, 'numeric');
            app.nEditField_2Label = uilabel(app.ModelParametersPanel_2);
            app.nEditField_2Label.Text = 'n';
            app.nEditField_2 = uieditfield(app.ModelParametersPanel_2, 'numeric');
            app.EditField_2Label = uilabel(app.ModelParametersPanel_2);
            app.EditField_2Label.Text = 'σ';
            app.EditField_2 = uieditfield(app.ModelParametersPanel_2, 'numeric');
            app.ReportButton = uibutton(app.Output4Tab, 'push');
            app.ReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReportButtonPushed, true);
            app.ReportButton.Text = 'Report';
            app.UnitsPanel = uipanel(app.Output4Tab);
            app.UnitsPanel.Title = 'Units';
            app.PowerUnitsDropDown_2Label = uilabel(app.UnitsPanel);
            app.PowerUnitsDropDown_2Label.Text = 'Power Units';
            app.PowerUnitsDropDown_2 = uidropdown(app.UnitsPanel);
            app.PowerUnitsDropDown_2.Items = {'dB', 'dBm'};
            app.PowerUnitsDropDown_2.Value = 'dBm';
            app.CalculateButtonGroup = uibuttongroup(app.Output4Tab);
            app.CalculateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalculateButtonGroupSelectionChanged, true);
            app.CalculateButtonGroup.Title = 'Calculate';
            app.TimeButton = uiradiobutton(app.CalculateButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Value = true;
            app.SeperationButton = uiradiobutton(app.CalculateButtonGroup);
            app.SeperationButton.Text = 'Seperation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end